classdef hyperviewer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DirectoryLabel                matlab.ui.control.Label
        DirectoryListBox              matlab.ui.control.ListBox
        ControlPanel                  matlab.ui.container.Panel
        UnmixAllButton                matlab.ui.control.Button
        SelectUnmixingAlgorithmDropDownLabel  matlab.ui.control.Label
        SelectUnmixingAlgorithmDropDown  matlab.ui.control.DropDown
        SelectDisplayImageDropDownLabel  matlab.ui.control.Label
        SelectDisplayImageDropDown    matlab.ui.control.DropDown
        ResetImageButton              matlab.ui.control.Button
        EnableParpoolCheckBox         matlab.ui.control.CheckBox
        UnmixingTimeEditFieldLabel    matlab.ui.control.Label
        UnmixingTimeEditField         matlab.ui.control.NumericEditField
        UnmixingTimeUnitLabel         matlab.ui.control.Label
        UnmixButton                   matlab.ui.control.Button
        BrightnessLabel               matlab.ui.control.Label
        BrightnessEditField           matlab.ui.control.NumericEditField
        DecreaseBrightnessButton      matlab.ui.control.Button
        IncreaseBrightnessButton      matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        HyperViewer2Label             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        msgbox                        matlab.ui.control.Label
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        BSLTable                      matlab.ui.control.Table
        BSLLabel                      matlab.ui.control.Label
        SaveBSLButton                 matlab.ui.control.Button
        ChooseAnalysisDropDownLabel   matlab.ui.control.Label
        ChooseAnalysisDropDown        matlab.ui.control.DropDown
        HarmonicEditFieldLabel        matlab.ui.control.Label
        HarmonicEditField             matlab.ui.control.NumericEditField
        ShowInSpectraViewerButton     matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        File                          matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        hyperCFMEMenu                 matlab.ui.container.Menu
        MaestroMenu                   matlab.ui.container.Menu
        BioFormatsMenu                matlab.ui.container.Menu
        DavisLabMenu                  matlab.ui.container.Menu
        ENVIMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        ReloadMenu                    matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAnalysisMenu              matlab.ui.container.Menu
        SaveAllAnalysesMenu           matlab.ui.container.Menu
        SaveAsTiffStacksMenu          matlab.ui.container.Menu
        SaveNewImageMenu              matlab.ui.container.Menu
        ExportAsMenu                  matlab.ui.container.Menu
        ExportMovieMenu               matlab.ui.container.Menu
        ExportNewBasisSpectraMenu     matlab.ui.container.Menu
        ExportTilesMenu               matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        SpectraViewerMenu             matlab.ui.container.Menu
        SpeedtestCPUMenu              matlab.ui.container.Menu
        SpeedtestGPUMenu              matlab.ui.container.Menu
        HistogramAnalysisMenu         matlab.ui.container.Menu
        ROIMenu                       matlab.ui.container.Menu
        NewROIMenu                    matlab.ui.container.Menu
        AddROIMenu                    matlab.ui.container.Menu
        NewPointMenu                  matlab.ui.container.Menu
        AddPointMenu                  matlab.ui.container.Menu
        AddROItoBSLMenu               matlab.ui.container.Menu
        ClearLastROIMenu              matlab.ui.container.Menu
        AverageExptMenu               matlab.ui.container.Menu
        PreunmixingMenu               matlab.ui.container.Menu
        PostunmixingMenu              matlab.ui.container.Menu
        PhasorDenoisingMenu           matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        IncludeAllSpectrainCompositeMenu  matlab.ui.container.Menu
        IncludeSaturatedPixelsMenu    matlab.ui.container.Menu
        GaussianBlurMenu              matlab.ui.container.Menu
        ApplyFlatfieldCorrectionMenu  matlab.ui.container.Menu
        GlobalROIMenu                 matlab.ui.container.Menu
        ApplyFiberImageFilterMenu     matlab.ui.container.Menu
        SamplingCorrectionMenu        matlab.ui.container.Menu
        SpectralSmoothingMenu         matlab.ui.container.Menu
        DemoMenu                      matlab.ui.container.Menu
        hyperCFMETestMenu             matlab.ui.container.Menu
        hyperCFMEDyesMenu             matlab.ui.container.Menu
        MaestrotestMenu               matlab.ui.container.Menu
        ENVItestapplesMenu            matlab.ui.container.Menu
        ENVItestbloodstainsMenu       matlab.ui.container.Menu
        OlympustestMenu               matlab.ui.container.Menu
        OIRtiffstestMenu              matlab.ui.container.Menu
        ZeissMenu                     matlab.ui.container.Menu
        DebugcodeMenu                 matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        ROIContextMenu                matlab.ui.container.ContextMenu
        NewROIMenu_2                  matlab.ui.container.Menu
        AddROIMenu_2                  matlab.ui.container.Menu
        NewPointMenu_2                matlab.ui.container.Menu
        AddPointMenu_2                matlab.ui.container.Menu
        AddROItoBSLMenu_2             matlab.ui.container.Menu
        ClearLastROIMenu_2            matlab.ui.container.Menu
        AnalysisContextMenu           matlab.ui.container.ContextMenu
        ShowErrorBarsMenu             matlab.ui.container.Menu
        ShowLegendMenu                matlab.ui.container.Menu
        SelectDataMenu                matlab.ui.container.Menu
        BrushMenu                     matlab.ui.container.Menu
        RectangleMenu                 matlab.ui.container.Menu
        LassoMenu                     matlab.ui.container.Menu
        EraserMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Initialize structs for data/variable organization
        % Experiment variables - these are only changed when a new expt is loaded
        expt = struct('dirname',    [], ... [str] Path to top-level directory containing images or subfolders of images
            'filetype',   [], ... [str] Indicates detected image filetype
            'mode',       'normal',  ... [str] Specify operation mode {'normal', 'reload', 'debug'}
            'debugExamp', 'off',     ... [str] filetype of debug example {'off', 'hyper', 'maestro', 'apples', 'blood', 'oir', 'oir-tiff', 'czi'}
            'bslFile',    'BSL.mat', ... [str] file name of the basis spectra library
            'loaded',     false, ... [logical] indicates expt has been loaded succesfully
            'loadvec',    []);   ... [dbl] vector of loading percentages per
            
        % Configuration variables for analysis
        cfg = struct('num_imgs',     0, ... [dbl] # of images in the expt
            'num_spec',     0, ... [dbl] # of basis spectra for image analysis
            'poolobj',     [], ... [pool] Parpool obj for CPU-unmixing
            'gpuUnmix',    [], ... [logical] idicates if gpu is available for unmixing
            'gpuDevCount', [], ... [dbl] # of usable gpus detected
            'gpuInfo',     [], ... [cell] info about local gpus returned by 'gpuDevice'
            'gpuIdx',      [], ... [dbl] index of the selected gpu configuration
            'algorithm',   [], ... [str] algorithm type {'gnnls', 'fnnls', 'lsqnonneg', 'fourier', 'chebyshev'}
            'ARCHSTR',     [], ... [str] detected computer architecture
            'roiIdx',       1, ... [dbl] index indicating the roi to apply
            'roiColors',   [], ...
            'fiber_img',   '1mmfiberimage.tif', ... [str] path to fiber core image for hyperCFME mask
            'fiber_mask',  [], ... [logical] logical mask of fiber cores
            'sigma',        4, ... [dbl] width of gaussian blurring filter in pixels
            'gaussfilt',   [], ... [dbl] gaussian filter for image blurring
            'phasor',      [], ... [struct] sub-struct for phasor analysis params
            'compress',    []);... [struct] sub-struct for image compression params
            
        %         % Keeps track of the gui state parameters used as variables.
        state = struct('img', 1,  ... [dbl] idx of currently selected image (only one at a time)
            'spec',        1,  ... [dbl] idx of currently selected basis spectra in A matrix (may be vector)
            'bsl',         1,  ... [dbl] idx of currently selected spectra in BSL (may be vector)
            'img_list',    [], ... [cell] array of image file names in img listbox
            'spec_list',   [], ... [cell] array of spectra names in matrix listbox
            'bsl_list',    []); ... [cell] array of spectra names in bsl listbox
        %             'brightness',  1,  ... [dbl] multiplier to brighten image on display
        %             'blur',        true,  ... [logical] apply spatial gaussian blur to image
        %             'fiberfilter', false, ... [logical] apply fiber core filter mask to image (hyperCFME only)
        %             'flatfield',   false, ... [logical] apply flatfield image correction
        %             'allspec',     false, ... [logical] include all spectra in composite (some are excluded by default)
        %             'satpix',      false, ... [logical] apply saturated pixel mask to exclude from analysis
        %             'globalroi',   false, ... [logical] use one roi on all other images
        %             'compress',    false, ... [logical] compress over-sampled images (hyperCFME only)
        %             'smoothSpec',  false, ... [logical] apply smoothing-spline spectral denoising (slow!! nor reccomended)
        %             'errorbars',   false,  ... [logical] show errorbars
        %             'legend',      false); ... [logical] show legend
        
        % Initialize image structure
%         img = hyperim;
       

        % Global GUI Messages
        msg = struct('nogpu',   'No compatible GPU device was found on this machine, NNLS decomposition analysis will be done on the CPU and might take a while.', ...
            'gpuEnabled',      'GPU decomposition enabled', ...
            'cpu_withparpool', 'CPU decomposition enabled with parallel pool cluster.', ...
            'cpu_noparpool',   'Sequential CPU decomposition enabled.' , ...
            'loadingexpt',     'Loading Experiment... Please Wait.', ...
            'loadingcube',     'Loading Image Cube... Please Wait.', ...
            'unmixing',        'Image analysis in progress, please wait.', ...
            'imageNotAnalzed', 'Error: This image has not been analyzed.', ...
            'loadexpt',        'Please load an expiriment first.', ...
            'loadBasis',       'Please add a basis spectra to the A matrix to analyze image.', ...
            'unmixall',        'Unmix all images first.');
        
        
    end
    
    methods (Access = private)
        
        function out = toggle(~, in)
            % input a binary variable and return its opposite value
            c = class(in);
            
            switch c
                case 'logical'
                    if in
                        out = false;
                    else
                        out = true;
                    end
                    
                case {'char', 'matlab.lang.OnOffSwitchState'}
                    if strcmp(in, 'on')
                        out = 'off';
                    elseif strcmp(in, 'off')
                        out = 'on';
                    end
            end
        end
        
        
        function ME = loadExperiment(app)
            
            % Update User
            app.msgbox.Text = app.msg.loadingcube;
            drawnow;
            
            % Message error is empty. 
            ME = [];
            
            % Get experiment directory
            switch app.expt.mode
                case 'normal'
                    % User selects directory of images
                    dirname = uigetdir(cd,'Select the parent folder that contains the set of images');
                    if dirname == 0
                        ME = 'User pressed cancel.';
                        return
                    end
                    
                    % Reset gui parameters
                    app.state.img  = 1;
                    app.state.spec = 1;
                    app.state.bsl  = 1;
                    app.state.flatfield = false;
                    
                case 'reload'
                    
                    % dirname already saved.
                    dirname = app.expt.dirname;
                    
                    % Retain current gui parameters
                case 'open'
                    
                    % TODO; add function to open old files
                    app.msgbox.Text = 'Sorry, this doesn''t work yet!';
                    drawnow;
                    return;
                    
                case 'debug'
                    switch app.expt.debugExamp
                        case 'hyper'
                            
                            dirname = [cd filesep 'data' filesep 'mivic'];
                            
                        case 'dyes'
                            
                            dirname = [cd filesep 'data' filesep 'soln'];
                            
                        case 'maestro'
                            
                            dirname = [cd filesep 'demo' filesep 'maestro'];
                            
                        case 'apples'
                            
                            dirname = [cd filesep 'demo' filesep 'apples'];
                            
                        case 'blood'
                            
                            dirname = [cd filesep 'data' filesep 'BloodStain_RPI'];
                            
                        case 'oir'
                            
                            dirname = [cd filesep 'data' filesep 'olympustest'];
                            
                        case 'oir-tiff'
                            
                            dirname = [cd filesep 'data' filesep 'OlympusTiffs'];
                            
                        case 'czi'
                            
                            dirname = [cd filesep 'data' filesep 'Zeiss'];
                            
                    end
                    
                    % Reset gui parameters
                    app.state.img  = 1;
                    app.state.spec = 1;
                    app.state.bsl  = 1;
                    app.state.flatfield = false;
            end
            
            % Get directory information
            try
                info = dir(dirname);
            catch err
                ME = err;
                return;
            end
            
            % Get rid of hidden directories (these begin with a '.')
            info(strncmp({info.name}, '.', 1)) = [];
            
            % Split files and folders
            folds = struct2cell(info);
            files = struct2cell(info);
            
            % Filter out single files and folders
            folds(:, [folds{5, :}] == 0) = [];
            files(:, [files{5, :}] == 1) = [];
            
            % Only need first row
            folds = {folds{1, :}};
            files = {files{1, :}};
            
            % Check for movie folder and remove from list
            movChk = strcmp(folds, 'movies');
            if any(movChk)
                folds(movChk) = [];
            end
            
            % EMK: Change endsWith to strfind for back-compatibility
            % Check for txt files and remove them
            txtChk = endsWith(files, '.txt');
            if any(txtChk)
                files(txtChk) = [];
            end
            
            % Check for spreadsheet files and remove them
            xlsChk = endsWith(files, '.xls');
            if any(xlsChk)
                files(xlsChk) = [];
            end
            
            % Check for matlab files and remove them
            matChk = endsWith(files, '.mat');
            if any(matChk)
                files(matChk) = [];
            end
            
            % Sort using natural sort pattern, otherwise default sort is incorrect
            % May have to do this for mFiles in future versions
            folds = natsort(folds);
            
            % Add any images in sub-folders to the image list. Note that 
            % for-loops wont run if 'folds' is empty
            switch app.expt.filetype
                
                case {'envi', 'oir', 'slice'}
                    % Start with files in main dir
                    paths = fullfile(dirname, files);
                    
                    % Get labels for each image
                    temp = strsplit(dirname, filesep);
                    temp = temp(end);
                    labels = cell(size(paths));
                    [labels(1:end)] = deal(temp);
                    
                    % Total number of files to start with
                    t = length(paths);
                    
                    for i = 1:size(folds, 2)
                        
                        % Get files in sub dir
                        if strcmp(app.expt.filetype, 'envi')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.dat']));
                            
                        elseif strcmp(app.expt.filetype, 'oir')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.oir']));
                            
                        elseif strcmp(app.expt.filetype, 'czi')
                            
                            files = struct2cell(dir([dirname filesep folds{1, i} filesep '*.czi']));
                            
                        end
                        
                        % Get rid of any directories
                        files(:, [files{5, :}] == 1) = [];
                        
                        % Number of images in subdir
                        n = size(files, 2);
                        
                        % Save path
                        paths((t + 1):(t + n)) = fullfile({files{2,:}}, {files{1,:}});
                        
                        % Save image label
                        [labels{(t + 1):(t + n)}] = deal(folds{1, i});
                        
                        % Update total
                        t = t + n;
                    end
                    
                    
                case {'hyper', 'maestro', 'oir-tiff'}
                    
                    % For these cases, only need the sub dirs
                    paths  = fullfile(dirname, folds);
                    labels = folds;
                    
                case {'czi'}
                    % Only get files in main dir and label them with filenames not
                    % folders - this way save all creates new dirs for each file
                    paths = fullfile(dirname, files);
                    labels = regexprep(files, '.czi', '');
            end
            
            % Number of images found
            n = length(paths);
            
            % Load percentages for display
            app.expt.loadvec    = linspace(0, 100, n + 1);
            app.expt.loadvec(1) = []; % Get rid of the leading 0
            
            % Experiment has been initialized
            app.expt.loaded = true;
            
            % Load BSL with relevant spectra
            app.lib = loadBSL(app.lib, 'all', false);
            app.state.bsl_list = {app.lib.tags}';
            
            % Load fiber image and create Mask
            if strcmp(app.expt.filetype, 'hyper')
                app.cfg = FME_mask(app.cfg, 'default');
            end
            
            % Set default image names
            filename = repmat({'image_not_loaded'}, [n 1]);
            
            % Save to structures
            [app.img(1:n).filename] = filename{:};
            [app.img(1:n).path]     = paths{:};
            [app.img(1:n).label]    = labels{:};
            [app.img.isLoaded]      = deal(false);
            [app.img.isUnmixed]     = deal(false);
            
            app.state.img_list      = filename;
            
            % Get image cubes from each subdirectory
            % for i = 1:handles.num_images
            % Get image cube
            %     [handles, ME] = getImageCube(i, handles);
            %     [handles, ~] = getImageCube(handles.whichImage, handles);
            
            %     if ~isempty(ME)
            %         warning('Ignoring image file %d of %d: %s \n Error: %s \n\n', ...
            %                  i, handles.num_images, handles.imPaths{i}, ME.message);
            %     end
            
            %     % Update user on progress
            %     handles.msgbox.String = sprintf('Loading %d Images ... %1.0f %%', ...
            %         handles.num_images, ...
            %         handles.percent_load(i));
            %     drawnow;
            % end
            
            % Load basis spectra
            switch app.expt.mode
                case 'normal'
                    % If spectra are loaded already,
                    if app.cfg.num_spec > 0
                        % Use them
                        [spec, app.lib] = loadSpectra(app.lib, 'current');
                    elseif strcmp(app.expt.filetype,'slice')==1
                        opt = 'slice_WL';
                        if any(size(dir([dirname filesep '**' filesep '*635*.tif']), 1)) ==1
                            opt = 'slice_635';
                        elseif any(size(dir([dirname filesep '**' filesep '*470*.tif']), 1)) ==1
                            opt = 'slice_470';
                        end
                        [spec, app.lib] = loadSpectra(app.BSL, opt);
                    else
                        % If not, use default
                        [spec, app.lib] = loadSpectra(app.BSL, app.expt.filetype);
                    end
                    
                case 'debug'
                    % Load BSL
                    [spec, app.lib] = loadSpectra(app.BSL, app.expt.debugExamp);
                    
                case 'reload'
                    % Use current spectra
                    [spec, app.lib] = loadSpectra(app.lib, 'current');
                    
            end
            
            % Update mat listbox contents
            app.state.spec_list = {spec.tags}';
            
            % Save vars
            app.expt.dirname = dirname;
            app.cfg.num_imgs = n;
            app.cfg.num_spec = length([spec.idx]);
            
            
            % Pre-load images
            % for i = [1, n]
            %    [img(i), ~] = getImageCube(img(i), expt.filetype);
            %    [img(i), ~] = unmixImage(img(i), handles);
            % end

        end
        
        function displayApp(app, varargin)
            
            app.msgbox.Text = 'Display Image.';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add library to MATLAB Path.
            addpath(genpath([cd filesep 'lib']));
            
            % Check for demo folder
            if isempty(genpath([cd filesep 'demo']))
                warning(sprintf(['Demo folder not detected, demos will not work. \n\n' ...
                    'Download the demo folder from www.springlabnu.com and copy to' ...
                    'Hyperviewer working directory'])); %#ok<SPWRN>
            else
                addpath(genpath([cd filesep 'demo']));
            end
            
            % Set default msgbox string:
            app.msgbox.Text = 'Click Load Experiment to begin.';
            
            %% Processor settings
            
            % Check computer OS
            app.cfg.ARCHSTR = computer('arch');
            
            % Universal algorithm configurations (don't need gpu)
            % Defaults are programmed into the dropdown menu already
            algstr = app.SelectUnmixingAlgorithmDropDown.Items;
            
            % TODO: Add phasor unmixing capabilities, not urgent
            % algstr{3} = 'CPU - Fourier Phasor';
            % algstr{4} = 'CPU - Chebyshev Phasor';
            
            numDefAlg = length(algstr);
            
            % Look for compatible GPUs on machine
            app.cfg.gpuDevCount = gpuDeviceCount;
            % app.gpuDevCount = 0;
            
            % Check for parpool. If no pool, do not create new one.
            app.cfg.poolobj = gcp('nocreate');
            
            if app.cfg.gpuDevCount == 0 % No gpu was found
                
                % Disable GPU unmixing
                app.cfg.gpuUnmix = false;
                app.cfg.algorithm = 'fnnls';
                
                if isempty(app.cfg.poolobj)
                    
                    % Query user to start a parpool (NNLSCPU is parfor enabled)
                    response = questdlg([app.msg.nogpu 'Would you like to enable ' ...
                        'the Parallel Computation Toolbox to speed up the '     ...
                        'analysis?'], 'WARNING: No GPU Device Found.', ...
                        'Yes', 'No', 'Yes');
                    
                    switch response
                        case 'Yes'
                            % Enable parpool
                            try
                                app.cfg.poolobj = parpool('local');
                                app.EnableParpoolCheckBox.Value = true;
                                
                                % Infom user about parpool
                                app.msgbox.Text = app.msg.cpu_withparpool;
                                
                            catch
                                app.msgbox.Text = 'Error: Unable to start parpool.';
                                app.EnableParpoolCheckBox.Value = false;
                            end

                        case 'No'
                            % Do nothing
                            % Inform user about algorithm status.
                            app.msgbox.Text = app.msg.cpu_noparpool;
                            app.EnableParpoolCheckBox.Value = false;
                    end
                    
                end
                
                
            else  % At least 1 GPU was found. TODO: Add option to choose number of GPUs
                
                % Enable GPU unmixing.
                app.cfg.gpuUnmix = true;
                app.cfg.algorithm = 'gnnls';
                app.msgbox.Text = app.msg.gpuEnabled;
                
                % Get gpu device names
                for i = 1:app.cfg.gpuDevCount
                    app.cfg.gpuInfo{i} = gpuDevice(i);
                    
                    % Add to list of algortim cfgs (4 defaults exist from above)
                    algstr{i + numDefAlg} = ['GPU - ' app.cfg.gpuInfo{i}.Name];
                end
                
                app.cfg.gpuIdx = [zeros(1, app.cfg.gpuDevCount) 0];
            end
            
            % Set string values
            app.SelectUnmixingAlgorithmDropDown.Items = algstr;
            app.SelectUnmixingAlgorithmDropDown.ItemsData = 1:length(algstr);
            
            % Defalut algorithm cfg
            if app.cfg.gpuUnmix
                % Start with first gpu device
                gpuDevice(1);
                app.cfg.gpuIdx(1) = 1;
                app.SelectUnmixingAlgorithmDropDown.Value = numDefAlg + 1;
            else
                app.SelectUnmixingAlgorithmDropDown.Value = 1;
            end
            
            %% TODO: Update loadBSL and Spectra
            %%  Load Basis Spectra File
            % load(app.expt.bslFile);
            
            % Set BSL and load spec names, colors, labels
            % app.lib = loadBSL(BSL, 'all', false);
            
            % Update BSL listbox
            % app.state.bsl_list = {lib.tags}';
            
            % Default A matrix is empty
            % [lib.matidx] = deal(false);
            
            % Load spectra
            % [spec, app.lib] = loadSpectra(app);
            
            % Update A_mat listbox
            % app.state.mat_list = {spec.tags}';
            
            %% Default parameters
            
            % Analysis params
            % How many pixels is the image over sampled?
            app.cfg.compress.x = 1;
            app.cfg.compress.y = 6;
            
            % Default harmonics for phasor plots
            app.cfg.phasor.f = 1;
            app.cfg.phasor.c = 2;
            
            % Gaussian mean filter to remove fiber cores
            app.cfg.gaussfilt = fspecial('gaussian', [6 6], app.cfg.sigma);
            
            app.cfg.roiColors = {[0.8 0.8 0.8], 'Green', 'Cyan', 'Yellow', 'Magenta', 'Red'};
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {726, 726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x', 426};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: hyperCFMEMenu
        function hyperCFMEMenuSelected(app, event)
           
            % Experiment mode
            app.expt.mode = 'normal';
            
            % Define filetype
            app.expt.filetype = 'hyper';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: MaestroMenu
        function MaestroMenuSelected(app, event)

            % Experiment mode
            app.expt.mode = 'normal';
            
            % Define filetype
            app.expt.filetype = 'maestro';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: BioFormatsMenu
        function BioFormatsMenuSelected(app, event)

            % Experiment mode
            app.expt.mode = 'normal';
            
            % Define filetype
            app.expt.filetype = 'bioformat';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: DavisLabMenu
        function DavisLabMenuSelected(app, event)

            % Experiment mode
            app.expt.mode = 'normal';
            
            % Define filetype
            app.expt.filetype = 'macrotome';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: ENVIMenu
        function ENVIMenuSelected(app, event)

            % Experiment mode
            app.expt.mode = 'normal';
            
            % Define filetype
            app.expt.filetype = 'envi';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)

            % Experiment mode
            app.expt.mode = 'open';
            
            % Load experiment
            flag = loadExperiment(app);
            if ~isempty(flag)
                app.msgbox.Text = flag;
                return;
            end
            
            % Display
            displayApp(app)
        end

        % Menu selected function: ReloadMenu
        function ReloadMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % Experiment mode
            app.expt.mode = 'reload';
            
            % Load Experiment Files
            flag = loadExperiment(app);
            if ~isempty(flag)
                % Display error message and return.
                app.msgbox.Text = flag;
                return;
            end
            
            % Reset to first image
            app.state.img = 1;
            
            % Display
            displayApp(app);
        end

        % Menu selected function: SaveAnalysisMenu
        function SaveAnalysisMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            app.msgbox.Text = 'Saving image data...';
            
            % Define directory to save results
            if app.cfg.gpuUnmix
                str = 'outputGPU';
            else
                str = 'outputCPU';
            end
            
            save_dir = [app.img.path filesep str filesep];
            
            % If folder doesn't exist, create directory
            if exist(save_dir, 'dir') ~= 7
                mkdir(save_dir);
            end
            
            % Save image file
            try
                writeImages(app.img(app.state.img), save_dir);
            catch
                warning('Could not save analysis: %s', app.img(app.state.img).label)
            end
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: SaveAllAnalysesMenu
        function SaveAllAnalysesMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            for n = 1:app.cfg.num_imgs
                % Define directory to save results
                if app.cfg.gpuUnmix
                    str = 'outputGPU';
                else
                    str = 'outputCPU';
                end
                
                save_dir = [app.img(n).path filesep str filesep];
                
                % If folder doesn't exist, create directory
                if exist(save_dir, 'dir') ~= 7
                    mkdir(save_dir);
                end
                
                % Save image file
                try
                    writeImages(app.img(n), save_dir);
                catch
                    warning('Could not save analysis: %s', app.img(app.state.img).label)
                end
                
                % Update user
                app.msgbox.Text = sprintf('Saving %d Images ... %1.0f %%', ...
                    app.cfg.num_imgs, app.expt.loadvec(n));
                drawnow;
            end
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: SaveAsTiffStacksMenu
        function SaveAsTiffStacksMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % Save all endmember basis maps as tiff stacks
            
            if app.cfg.gpuUnmix
                str = 'outputGPU';
            else
                str = 'outputCPU';
            end
            save_dir = [app.expt.dirname filesep str filesep];
            subdirs = {['color_composites' filesep], ['color_basis' filesep], ['bw_basis' filesep]};
            
            % If folder doesn't exist, create directory
            if exist(save_dir, 'dir') ~= 7
                mkdir(save_dir);
                for i = 1:length(subdirs)
                    mkdir([save_dir subdirs{i}]);
                end
            end
            
            for n = 1:app.cfg.num_imgs
                if strcmp(app.img(n).filename, 'NOT_LOADED') || ~app.img(n).isUnmixed
                    continue;
                else
                    
                    %try
                    writeBasisAsTiffStack(app.img(n), save_dir, subdirs, app.img(n).filename);
                    %catch
                    %    warning('Could not save image file %d of %d: %s \n', ...
                    %        n, cfg.num_imgs, handles.imPaths{n});
                    
                    %end
                    
                end
                % Update user on progress
                app.msgbox.Text = sprintf('Saving %d Images ... %1.0f %%', ...
                    app.cfg.num_imgs, app.expt.loadvec(n));
                drawnow;
                
            end
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: SaveNewImageMenu
        function SaveNewImageMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            %  Define directory to save results
            save_dir = [app.img(app.state.img).path filesep];
            
            % If folder doesn't exist, create directory
            if exist(save_dir, 'dir') ~= 7
                mkdir(save_dir);
            end
            
            % Save image file
            writeImageCube(app.img(app.state.img), app.expt.filetype, save_dir);
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: ExportMovieMenu
        function ExportMovieMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % All images must be unmixed
            if any(~[app.img.isLoaded])
                app.msgbox.Text = app.msg.unmixall;
                return;
            end
            
            % TODO: Get user input to determine this
            % Desired video framerate
            fps = 15;
            
            % Define and create path to save video
            vid_path = [app.expt.dirname filesep 'movies'];
            if exist(vid_path, 'dir') ~= 7
                mkdir(vid_path);
            end
            
            app.msgbox.Text = 'Loading files...'; drawnow;
            
            % Initialize
            rawIm = cell(app.cfg.num_imgs, 1);
            compIm = cell(app.cfg.num_imgs, 1);
            specfilt = [app.spec.filt];
            
            % Construct videos
            for n = 1:app.cfg.num_imgs
                
                wlfilt = [app.img(n).filt];
                
                rawim = sum(cat(4, app.img(n).colorims.cube(wlfilt).im), 4) / sum(wlfilt);
                
                rawIm{n} = imadjust(rawim, stretchlim(rawim, 0), []);
                
                compIm{n} = sum(cat(4, app.img(n).colorims.basis(specfilt).im), 4) ...
                    * app.state.brightness;
                
                % TODO: Hard code, fix this
                egfrbasis{n} = app.img(n).colorims.basis(5).im;
            end
            
            app.msgbox.Text = 'Writing Raw image video'; drawnow;
            
            % Write the raw composite image to video
            writeVideoFiles(rawIm, [vid_path filesep 'rawCompVideo.mp4'],...
                fps, app);
            
            writeVideoFiles(egfrbasis, [vid_path filesep 'EGFRBasisVideo.mp4'],...
                fps, app);
            
            app.msgbox.Text = 'Writing Composite Video'; drawnow;
            
            % Write Composite Basis Spectra Video
            writeVideoFiles(compIm, [vid_path filesep 'basisCompVideo.mp4'], ...
                fps, app);
            
            % idx = find(handles.matidx);
            %
            % % Write individual basis spectra movies
            % for i = 1:handles.num_spec
            %
            %     vid_name = ['basisVideo ' handles.specNames{idx(i)}   '_' ...
            %                               handles.specColors{idx(i)}  '_' ...
            %                               handles.specTargets{idx(i)} '.avi'];
            %
            %     writeVideoFiles(handles.basisIms(i, :), [vid_path filesep vid_name], ...
            %                fps, handles);
            %
            % end
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: ExportNewBasisSpectraMenu
        function ExportNewBasisSpectraMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % For calibration images, exports the average image spectrum of each image
            % TODO: allow user to select which type of ROI to output
            
            % Get the averages from roi's in each image
            % Indv averages from each image
            spec = [app.img.roi.savg{:}];
            
            % Average over images
            % spec = mean([handles.specavg{:}], 2);
            
            % Both
            % spec = [[handles.specavg{:}] mean([handles.specavg{:}], 2)];
            
            % Save matrix
            filename = [app.expt.dirname filesep 'AverageSpectra.txt'];
            save(filename, 'spec', '-ascii', '-double', '-tabs')
            
            app.msgbox.String = 'Ready.';
        end

        % Menu selected function: ExportTilesMenu
        function ExportTilesMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            app.msgbox.Text = 'Position the rectangle to crop image. Double-click to select region.';
            
            %  Define directory to save results
            if app.cfg.gpuUnmix
                str = 'outputGPU';
            else
                str = 'outputCPU';
            end
            save_dir = [app.img(app.state.img).path filesep str filesep];
            
            
            % If folder doesn't exist, create directory
            if exist(save_dir, 'dir') ~= 7
                mkdir(save_dir);
            end
            
            % Generate image
            tiles = makeTiledImage(app.img(app.state.img), app);
            
            % Save image
            imwrite(tiles, [save_dir 'tiled_basis_images.tif']);
            
            % assignin('base', 'im', tiles);
            
            app.msgbox.Text = 'Ready.';
        end

        % Menu selected function: SpectraViewerMenu
        function SpectraViewerMenuSelected(app, event)
            % TODO
            app.msgbox.Text = 'Sorry, this function doesn''t work yet.';
            
        end

        % Menu selected function: SpeedtestCPUMenu
        function SpeedtestCPUMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO: port to separate file
        end

        % Menu selected function: SpeedtestGPUMenu
        function SpeedtestGPUMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO: port to separate file
            
        end

        % Menu selected function: HistogramAnalysisMenu
        function HistogramAnalysisMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO: port to separate file
        end

        % Menu selected function: NewROIMenu, NewROIMenu_2
        function NewROIMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            if app.state.globalroi
                % Update global ROI setting
                app.cfg.roiIdx = app.state.img;
            end
            
            % Current image
            n = app.state.img;
            
            % Number of ROIs on image - replaces last ROI in list
            nROI = length(app.img(n).roi);
            
            % Inform user
            app.msgbox.Text = ['Click to drop points on the image and define the roi.', ...
                              'After closing the regoin, double click to return'];
            
            % Get region
            app.img(n).roi(nROI) = getRegion(size(app.img(n).disp), 'roi');
            
            % Calc ROI Stats
            app.img(n).roiStats(nROI) = getRegionStats(app.img(n), app.img(n).roi(nROI).mask);
            
            % Unmix and display image again
            displayApp(app);
        end

        % Menu selected function: AddROIMenu, AddROIMenu_2
        function AddROIMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            if app.state.globalroi
                % Update global ROI setting
                app.cfg.roiIdx = app.state.img;
            end
            
            % Current image
            n = app.state.img;
            
            % Number of ROIs on image - append ROI to list
            nROI = length(app.img(n).roi) + 1;
            
            % Inform user
            app.msgbox.Text = ['Click to drop points on the image and define the roi.', ...
                              'After closing the regoin, double click to return'];
            
            % Get region
            app.img(n).roi(nROI) = getRegion(size(app.img(n).disp), 'roi');
            
            % Calc ROI Stats
            app.img(n).roiStats(nROI) = getRegionStats(app.img(n), app.img(n).roi(nROI).mask);
            
            % Unmix and display image again
            displayApp(app);
        end

        % Menu selected function: NewPointMenu, NewPointMenu_2
        function NewPointMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            if app.state.globalroi
                % Update global ROI setting
                app.cfg.roiIdx = app.state.img;
            end
            
            % Current image
            n = app.state.img;
            
            % Number of ROIs on image - replaces last ROI in list
            nROI = length(app.img(n).roi);
            
            % Inform user
            app.msgbox.Text = 'Select a point to analyze.';
            
            % Get region
            app.img(n).roi(nROI) = getRegion(size(app.img(n).disp), 'point');
            
            % Calc ROI Stats
            app.img(n).roiStats(nROI) = getRegionStats(app.img(n), app.img(n).roi(nROI).mask);
            
            % Unmix and display image again
            displayApp(app);
        end

        % Menu selected function: AddPointMenu, AddPointMenu_2
        function AddPointMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            if app.state.globalroi
                % Update global ROI setting
                app.cfg.roiIdx = app.state.img;
            end
            
            % Current image
            n = app.state.img;
            
            % Number of ROIs on image - append ROI to list
            nROI = length(app.img(n).roi) + 1;
            
            % Inform user
            app.msgbox.Text = 'Select a point to analyze.';
            
            % Get region
            app.img(n).roi(nROI) = getRegion(size(app.img(n).disp), 'point');
            
            % Calc ROI Stats
            app.img(n).roiStats(nROI) = getRegionStats(app.img(n), app.img(n).roi(nROI).mask);
            
            % Unmix and display image again
            displayApp(app);
        end

        % Menu selected function: AddROItoBSLMenu, AddROItoBSLMenu_2
        function AddROItoBSLMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO
            
        end

        % Menu selected function: ClearLastROIMenu, 
        % ClearLastROIMenu_2
        function ClearLastROIMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            if app.state.globalroi
                % Update global ROI setting
                app.cfg.roiIdx = app.state.img;
            end
            
            % Number of ROIs on image
            nROI = length(app.img(app.state.img).roi);
            
            if nROI > 1
                % Clear the last ROI entry
                app.img(app.state.img).roi(nROI) = [];
                app.img(app.state.img).roiStats(nROI) = [];
                
            else
                return;
            end
            
            % Unmix and display image again
            displayApp(app);
        end

        % Menu selected function: PreunmixingMenu
        function PreunmixingMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO
        end

        % Menu selected function: PostunmixingMenu
        function PostunmixingMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO
        end

        % Menu selected function: PhasorDenoisingMenu
        function PhasorDenoisingMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % TODO
        end

        % Menu selected function: IncludeAllSpectrainCompositeMenu
        function IncludeAllSpectrainCompositeMenuSelected(app, event)
            % Toggle value
            app.IncludeAllSpectrainCompositeMenu.Checked = toggle(app, app.IncludeAllSpectrainCompositeMenu.Checked);
            
            displayApp(app);
        end

        % Menu selected function: IncludeSaturatedPixelsMenu
        function IncludeSaturatedPixelsMenuSelected(app, event)
            % Toggle value
            app.IncludeSaturatedPixelsMenu.Checked = toggle(app, app.IncludeSaturatedPixelsMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app);
        end

        % Menu selected function: GaussianBlurMenu
        function GaussianBlurMenuSelected(app, event)
            % Toggle value
            app.GaussianBlurMenu.Checked = toggle(app, app.GaussianBlurMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app)
        end

        % Menu selected function: ApplyFlatfieldCorrectionMenu
        function ApplyFlatfieldCorrectionMenuSelected(app, event)
            % Check for experiment
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            % All images must be unmixed
            if any(~[app.img.isLoaded])
                app.msgbox.Text = app.msg.unmixall;
                return;
            end
            
            % Toggle value
            app.ApplyFlatfieldCorrectionMenu.Checked = toggle(app, app.ApplyFlatfieldCorrectionMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app)
        end

        % Menu selected function: GlobalROIMenu
        function GlobalROIMenuSelected(app, event)
            % Toggle value
            app.GlobalROIMenu.Checked = toggle(app, app.GlobalROIMenu.Checked);
            
            % Update roiIdx to current selected image
            app.cfg.roiIdx = app.state.img;
            
            displayApp(app)
        end

        % Menu selected function: ApplyFiberImageFilterMenu
        function ApplyFiberImageFilterMenuSelected(app, event)
            % Toggle value
            app.ApplyFiberImageFilterMenu.Checked = toggle(app, app.ApplyFiberImageFilterMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app)
        end

        % Menu selected function: SamplingCorrectionMenu
        function SamplingCorrectionMenuSelected(app, event)
            % Toggle value
            app.SamplingCorrectionMenu.Checked = toggle(app, app.SamplingCorrectionMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app)
        end

        % Menu selected function: SpectralSmoothingMenu
        function SpectralSmoothingMenuSelected(app, event)
            
            if strcmp(app.SpectralSmoothingMenu.Checked, 'off')
                % Double check with user to turn this on
                answer = questdlg(['Spectral smoothing can take a long time to compute. ', ...
                    'Are you sure you want to continue?'], 'Warning!', ...
                    'Yes', 'No', 'No');
                switch answer
                    case 'No'
                        return;
                    case 'Yes'
                        % continue
                end
            end
            
            % Toggle value
            app.SpectralSmoothingMenu.Checked = toggle(app, app.SpectralSmoothingMenu.Checked);
            
            % Images need to be re-analyzed
            if app.expt.loaded
                [app.img(:).isUnmixed] = deal(false);
            end
            
            displayApp(app)
        end

        % Menu selected function: hyperCFMETestMenu
        function hyperCFMETestMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'hyper';
            app.expt.debugExamp  = 'hyper';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: MaestrotestMenu
        function MaestrotestMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'maestro';
            app.expt.debugExamp  = 'maestro';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: ENVItestapplesMenu
        function ENVItestapplesMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'envi';
            app.expt.debugExamp  = 'apples';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: ENVItestbloodstainsMenu
        function ENVItestbloodstainsMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'envi';
            app.expt.debugExamp  = 'blood';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: OlympustestMenu
        function OlympustestMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'oir';
            app.expt.debugExamp  = 'oir';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: OIRtiffstestMenu
        function OIRtiffstestMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'oir-tiff';
            app.expt.debugExamp  = 'oir-tiff';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: ZeissMenu
        function ZeissMenuSelected(app, event)
            app.expt.mode        = 'debug';
            app.expt.filetype    = 'czi';
            app.expt.debugExamp  = 'czi';
            app.msgbox.String    = app.msg.loadingexpt;
            
            % Load Experiment Files
            ME = loadExperiment(app);
            if ~isempty(ME)
                % Display error message and return.
                app.msgbox.Text = ME;
                return;
            end
            
            % Display
            displayApp(app);
        end

        % Menu selected function: ShowErrorBarsMenu
        function ShowErrorBarsMenuSelected(app, event)
            % Toggle value
            app.ShowErrorBarsMenu.Checked = toggle(app, app.ShowErrorBarsMenu.Checked);
            
            displayApp(app)
        end

        % Menu selected function: ShowLegendMenu
        function ShowLegendMenuSelected(app, event)
            % Toggle value
            app.ShowLegendMenu.Checked = toggle(app, app.ShowLegendMenu.Checked);
            
            displayApp(app)
        end

        % Menu selected function: BrushMenu
        function BrushMenuSelected(app, event)
            n = app.state.img;
            
            % selectdata settings
            %  Valid Properties: 'Action', 'Axes', 'BrushSize', 'Identify',
            %           'Ignore' , 'Pointer', 'SelectionMode'
            
            args = {'Action',        'list', ...        {'list', 'delete'}
                    'Axes',          app.UIAxes3, ...
                    'Identify',      'on', ...          {'off', 'on'}
                    'Ignore',        [], ...            (handle of dataset to exclude in selection)
                    'Return',        'selected', ...    {'selected' | 'unselected' }
                    'SelectionMode', 'Brush', ...       {'Lasso', 'Brush', 'Rect', 'Closest'}
                    'Verify',        'off'}; ...        { 'off' | 'on' }
                
            % Scatter plot sometimes includes basis phasor points, exlude them
            if exist('app.scat_basis', 'var')
                if isvalid(app.scat_basis)
                    args{8} = app.scat_basis;
                end
            end
            dims = size(app.img(n).disp);
            
            % Number of ROIs
            nROI = length(app.img(n).roi);
            
            % get region from scatterplot
            app.img(n).roi(nROI + 1) = getRegion(dims, 'scatter', args);
            app.img(n).roiStats(nROI + 1) = getRegionStats(app.img(n), app.img(n).roi(nROI+1).mask);
            
            % Update display
            displayApp(app);
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
            n = app.state.img;
            
            % selectdata settings
            %  Valid Properties: 'Action', 'Axes', 'BrushSize', 'Identify',
            %           'Ignore' , 'Pointer', 'SelectionMode'
            
            args = {'Action',        'list', ...        {'list', 'delete'}
                    'Axes',          app.UIAxes3, ...
                    'Identify',      'on', ...          {'off', 'on'}
                    'Ignore',        [], ...            (handle of dataset to exclude in selection)
                    'Return',        'selected', ...    {'selected' | 'unselected' }
                    'SelectionMode', 'Rect', ...       {'Lasso', 'Brush', 'Rect', 'Closest'}
                    'Verify',        'off'}; ...        { 'off' | 'on' }
                
            % Scatter plot sometimes includes basis phasor points, exlude them
            if exist('app.scat_basis', 'var')
                if isvalid(app.scat_basis)
                    args{8} = app.scat_basis;
                end
            end
            dims = size(app.img(n).disp);
            
            % Number of ROIs
            nROI = length(app.img(n).roi);
            
            % get region from scatterplot
            app.img(n).roi(nROI + 1) = getRegion(dims, 'scatter', args);
            app.img(n).roiStats(nROI + 1) = getRegionStats(app.img(n), app.img(n).roi(nROI+1).mask);
            
            % Update display
            displayApp(app);
        end

        % Menu selected function: LassoMenu
        function LassoMenuSelected(app, event)
            n = app.state.img;
            
            % selectdata settings
            %  Valid Properties: 'Action', 'Axes', 'BrushSize', 'Identify',
            %           'Ignore' , 'Pointer', 'SelectionMode'
            
            args = {'Action',        'list', ...        {'list', 'delete'}
                    'Axes',          app.UIAxes3, ...
                    'Identify',      'on', ...          {'off', 'on'}
                    'Ignore',        [], ...            (handle of dataset to exclude in selection)
                    'Return',        'selected', ...    {'selected' | 'unselected' }
                    'SelectionMode', 'Lasso', ...       {'Lasso', 'Brush', 'Rect', 'Closest'}
                    'Verify',        'off'}; ...        { 'off' | 'on' }
                
            % Scatter plot sometimes includes basis phasor points, exlude them
            if exist('app.scat_basis', 'var')
                if isvalid(app.scat_basis)
                    args{8} = app.scat_basis;
                end
            end
            dims = size(app.img(n).disp);
            
            % Number of ROIs
            nROI = length(app.img(n).roi);
            
            % get region from scatterplot
            app.img(n).roi(nROI + 1) = getRegion(dims, 'scatter', args);
            app.img(n).roiStats(nROI + 1) = getRegionStats(app.img(n), app.img(n).roi(nROI+1).mask);
            
            % Update display
            displayApp(app);
        end

        % Menu selected function: EraserMenu
        function EraserMenuSelected(app, event)
            n = app.state.img;
            
            % selectdata settings
            %  Valid Properties: 'Action', 'Axes', 'BrushSize', 'Identify',
            %           'Ignore' , 'Pointer', 'SelectionMode'
            
            args = {'Action',        'delete', ...        {'list', 'delete'}
                    'Axes',          app.UIAxes3, ...
                    'Identify',      'on', ...          {'off', 'on'}
                    'Ignore',        [], ...            (handle of dataset to exclude in selection)
                    'Return',        'selected', ...    {'selected' | 'unselected' }
                    'SelectionMode', 'Brush', ...       {'Lasso', 'Brush', 'Rect', 'Closest'}
                    'Verify',        'off'}; ...        { 'off' | 'on' }
                
            % Scatter plot sometimes includes basis phasor points, exlude them
            if exist('app.scat_basis', 'var')
                if isvalid(app.scat_basis)
                    args{8} = app.scat_basis;
                end
            end
            dims = size(app.img(n).disp);
            
            % Number of ROIs
            nROI = length(app.img(n).roi);
            
            % get region from scatterplot
            app.img(n).roi(nROI + 1) = getRegion(dims, 'scatter', args);
            app.img(n).roiStats(nROI + 1) = getRegionStats(app.img(n), app.img(n).roi(nROI+1).mask);
            
            % Update display
            displayApp(app);
        end

        % Cell edit callback: BSLTable
        function BSLTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % TODO go and update the BSL
            
            
        end

        % Cell selection callback: BSLTable
        function BSLTableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Value changed function: DirectoryListBox
        function DirectoryListBoxValueChanged(app, event)
            app.state.img = app.DirectoryListBox.Value;
            
            displayApp(app)
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            
        end

        % Value changed function: SelectUnmixingAlgorithmDropDown
        function SelectUnmixingAlgorithmDropDownValueChanged(app, event)
            value = app.SelectUnmixingAlgorithmDropDown.Value;
            
            switch value
                
            end
        end

        % Value changed function: SelectDisplayImageDropDown
        function SelectDisplayImageDropDownValueChanged(app, event)
            
            displayApp(app)
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            
            if ~app.expt.loaded
                app.msgbox.Text = app.msg.loadexpt;
                return;
            end
            
            app.msgbox.Text = 'Unmixing all images...  0%';
            
            % Switch to axes3 to display unmixing stats
            app.UIAxes3; hold on;
            title('Unmixing Speed');
            xlabel('Image #');
            ylabel('Time (s)');
            xlim([0 app.cfg.num_imgs]);
            legend off;
            set(gca, 'YScale', 'log');
            
            
            t_load = zeros(1, app.cfg.num_imgs);
            lag    = zeros(1, app.cfg.num_imgs);
            
            % Analyze images images that have not been unmixed.
            for n = 1:app.cfg.num_imgs
                
                % If image has not been loaded, load it
                if ~app.img(n).isLoaded
                    
                    % Load image
                    tic
                    [app.img(n), ME] = hyperim.getImageCube(app.img(n), app.expt.filetype);
                    t_load(n) = toc;
                    
                    % Update filename before checking the error
                    app.DirectoryListBox.Items = {app.img.filename};
                    
                    if ~isempty(ME)
                        app.msgbox.Text = ME;
                        warning(ME);
                    end
                end
                
                
                % Unmix image (here, do not skip the analysis if already done,
                % this makes sure all images are analyzed with the same parameters)
                if strcmp(handles.img(n).filename, 'NOT_LOADED')
                    continue;
                else
                    [app.img(n), ME] = unmixImage(app.img(n), app);
                end
                if ~isempty(ME)
                    app.msgbox.Text = app.msg.nogpu;
                    warning(ME);
                end
                
                % Get region stats
                app.img(n).roiStats = getRegionStats(app.img(n), app.img(app.cfg.roiIdx).roi.mask);
                
                lag(n) = t_load(n) - app.img(n).evalTime;
                plot(n, t_load(n), 'b*', n, lag(n), 'ro');
                ylim([-inf inf]);
                
                app.msgbox.Text = sprintf('Unmixing all images... %1.0f %%', ...
                    app.expt.loadvec(n));
                drawnow;
                
            end
            
            app.msgbox.Text = 'Ready.';
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            
            % TODO Reset roi's on image
            
            displayApp(app)
        end

        % Value changed function: EnableParpoolCheckBox
        function EnableParpoolCheckBoxValueChanged(app, event)
            
            
            switch app.EnableParpoolCheckBox.Value
                case false
                    % Get rid of parpool object.
                    if ~isempty(app.cfg.poolobj)
                        delete(app.cfg.poolobj);
                    end
                    
                case true
                    % Enable parpool
                    if isempty(app.cfg.poolobj)
                        try
                            app.cfg.poolobj = parpool('local');
                        catch
                            app.msgbox.Text = 'Error: Unable to create parpool';
                            app.EnableParpoolCheckBox.Value = false;
                        end
                    end
            end
            
            % displayApp(app)
        end

        % Callback function
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            
            displayApp(app)
        end

        % Value changed function: ChooseAnalysisDropDown
        function ChooseAnalysisDropDownValueChanged(app, event)
            
            switch app.ChooseAnalysisDropDown.Value
                case {1, 2} % Spectral Fit, Histogram
                    
                    % Update context menu
                    app.SelectDataMenu.Enable = false;
                    app.ShowErrorBarsMenu.Enable = true;
                    
                    % Disable Harmonic Edit Field
                    app.HarmonicEditField.Enable = false;
                    app.HarmonicEditFieldLabel.Enable = false;
                    
                    
                case {3, 4} % Fourier, Chebyshev Phasor Plot
                    
                    % Update context menu
                    app.SelectDataMenu.Enable = true;
                    app.ShowErrorBarsMenu.Enable = false;
                    
                    % Enable Harmonic Edit Field
                    app.HarmonicEditField.Enable = true;
                    app.HarmonicEditFieldLabel.Enable = true;
                    
                    % Set default phasor harmonic
                    if app.ChooseAnalysisDropDown.Value == 3
                        app.HarmonicEditField.Value = app.cfg.phasor.f;
                    else
                        app.HarmonicEditField.Value = app.cfg.phasor.c;
                    end
            end
            
            displayApp(app)
        end

        % Menu selected function: DebugcodeMenu
        function DebugcodeMenuSelected(app, event)
            test = 1; %#ok<NASGU>
        end

        % Value changed function: HarmonicEditField
        function HarmonicEditFieldValueChanged(app, event)
            
            switch app.SelectUnmixingAlgorithmDropDown.Value
                case 3
                    app.cfg.phasor.f = app.HarmonicEditField.Value;
                case 4
                    app.cfg.phasor.c = app.HarmonicEditField.Value;
            end
            
            displayApp(app)
        end

        % Button pushed function: SaveBSLButton
        function SaveBSLButtonPushed(app, event)
            
        end

        % Button pushed function: ShowInSpectraViewerButton
        function ShowInSpectraViewerButtonPushed(app, event)
            
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            
            displayApp(app)
        end

        % Button pushed function: DecreaseBrightnessButton
        function DecreaseBrightnessButtonPushed(app, event)
            % Decrement by 1
            if app.BrightnessEditField.Value >= 1
                app.BrightnessEditField.Value = app.BrightnessEditField.Value - 1;
            else
                app.BrightnessEditField.Value = 0;
            end
            
            displayApp(app)
        end

        % Button pushed function: IncreaseBrightnessButton
        function IncreaseBrightnessButtonPushed(app, event)
            % Increment by 1
            app.BrightnessEditField.Value = app.BrightnessEditField.Value + 1;
            
            displayApp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DirectoryLabel = uilabel(app.LeftPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.DirectoryListBox = uilistbox(app.LeftPanel);
            app.DirectoryListBox.Items = {};
            app.DirectoryListBox.ValueChangedFcn = createCallbackFcn(app, @DirectoryListBoxValueChanged, true);
            app.DirectoryListBox.Value = {};
            app.ControlPanel = uipanel(app.LeftPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.UnmixAllButton = uibutton(app.ControlPanel, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix All';
            app.SelectUnmixingAlgorithmDropDownLabel = uilabel(app.ControlPanel);
            app.SelectUnmixingAlgorithmDropDownLabel.Text = 'Select Unmixing Algorithm';
            app.SelectUnmixingAlgorithmDropDown = uidropdown(app.ControlPanel);
            app.SelectUnmixingAlgorithmDropDown.Items = {'CPU - FNNLS', 'CPU - LSQNONNEG'};
            app.SelectUnmixingAlgorithmDropDown.ItemsData = [1 2];
            app.SelectUnmixingAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectUnmixingAlgorithmDropDownValueChanged, true);
            app.SelectUnmixingAlgorithmDropDown.Value = 1;
            app.SelectDisplayImageDropDownLabel = uilabel(app.ControlPanel);
            app.SelectDisplayImageDropDownLabel.Text = 'Select Display Image';
            app.SelectDisplayImageDropDown = uidropdown(app.ControlPanel);
            app.SelectDisplayImageDropDown.Items = {'Unmixed Composite', 'Lambda Composite', 'Reduced Chi-squared Map'};
            app.SelectDisplayImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDisplayImageDropDownValueChanged, true);
            app.SelectDisplayImageDropDown.Value = 'Unmixed Composite';
            app.ResetImageButton = uibutton(app.ControlPanel, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.EnableParpoolCheckBox = uicheckbox(app.ControlPanel);
            app.EnableParpoolCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableParpoolCheckBoxValueChanged, true);
            app.EnableParpoolCheckBox.Text = 'Enable Parpool';
            app.UnmixingTimeEditFieldLabel = uilabel(app.ControlPanel);
            app.UnmixingTimeEditFieldLabel.Text = 'Unmixing Time';
            app.UnmixingTimeEditField = uieditfield(app.ControlPanel, 'numeric');
            app.UnmixingTimeEditField.Limits = [0 Inf];
            app.UnmixingTimeEditField.ValueDisplayFormat = '%5.2f';
            app.UnmixingTimeEditField.Editable = 'off';
            app.UnmixingTimeUnitLabel = uilabel(app.ControlPanel);
            app.UnmixingTimeUnitLabel.Text = 's';
            app.UnmixButton = uibutton(app.ControlPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix';
            app.BrightnessLabel = uilabel(app.ControlPanel);
            app.BrightnessLabel.Text = 'Brightness:';
            app.BrightnessEditField = uieditfield(app.ControlPanel, 'numeric');
            app.BrightnessEditField.Limits = [0 Inf];
            app.BrightnessEditField.ValueDisplayFormat = '%2.1f';
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessEditField.Value = 1;
            app.DecreaseBrightnessButton = uibutton(app.ControlPanel, 'push');
            app.DecreaseBrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseBrightnessButtonPushed, true);
            app.DecreaseBrightnessButton.Text = '–';
            app.IncreaseBrightnessButton = uibutton(app.ControlPanel, 'push');
            app.IncreaseBrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseBrightnessButtonPushed, true);
            app.IncreaseBrightnessButton.Text = '+';
            app.CenterPanel = uipanel(app.GridLayout);
            app.HyperViewer2Label = uilabel(app.CenterPanel);
            app.HyperViewer2Label.Text = 'HyperViewer2';
            app.StatusLabel = uilabel(app.CenterPanel);
            app.StatusLabel.Text = 'Status: ';
            app.msgbox = uilabel(app.CenterPanel);
            app.msgbox.Text = '';
            app.UIAxes1 = uiaxes(app.CenterPanel);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.CenterPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.BSLTable = uitable(app.RightPanel);
            app.BSLTable.ColumnName = {'A matrix?'; 'Dye'; 'Target'; 'Color'};
            app.BSLTable.RowName = {};
            app.BSLTable.CellEditCallback = createCallbackFcn(app, @BSLTableCellEdit, true);
            app.BSLTable.CellSelectionCallback = createCallbackFcn(app, @BSLTableCellSelection, true);
            app.BSLLabel = uilabel(app.RightPanel);
            app.BSLLabel.Text = 'BSL';
            app.SaveBSLButton = uibutton(app.RightPanel, 'push');
            app.SaveBSLButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBSLButtonPushed, true);
            app.SaveBSLButton.Text = 'Save BSL';
            app.ChooseAnalysisDropDownLabel = uilabel(app.RightPanel);
            app.ChooseAnalysisDropDownLabel.Text = 'Choose Analysis:';
            app.ChooseAnalysisDropDown = uidropdown(app.RightPanel);
            app.ChooseAnalysisDropDown.Items = {'Spectral Fit', 'Histogram', 'Fourier Phasor Plot', 'Chebyshev Phasor Plot'};
            app.ChooseAnalysisDropDown.ItemsData = [1 2 3 4];
            app.ChooseAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseAnalysisDropDownValueChanged, true);
            app.ChooseAnalysisDropDown.Value = 1;
            app.HarmonicEditFieldLabel = uilabel(app.RightPanel);
            app.HarmonicEditFieldLabel.Enable = 'off';
            app.HarmonicEditFieldLabel.Text = 'N =';
            app.HarmonicEditField = uieditfield(app.RightPanel, 'numeric');
            app.HarmonicEditField.ValueChangedFcn = createCallbackFcn(app, @HarmonicEditFieldValueChanged, true);
            app.HarmonicEditField.Enable = 'off';
            app.ShowInSpectraViewerButton = uibutton(app.RightPanel, 'push');
            app.ShowInSpectraViewerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInSpectraViewerButtonPushed, true);
            app.ShowInSpectraViewerButton.Text = 'Show In SpectraViewer';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Analysis')
            xlabel(app.UIAxes3, 'Wavelength (nm)')
            app.UIAxes3.XLim = [0 1];
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.NewMenu = uimenu(app.File);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.hyperCFMEMenu = uimenu(app.NewMenu);
            app.hyperCFMEMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMEMenuSelected, true);
            app.hyperCFMEMenu.Text = 'hyperCFME';
            app.MaestroMenu = uimenu(app.NewMenu);
            app.MaestroMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestroMenuSelected, true);
            app.MaestroMenu.Text = 'Maestro';
            app.BioFormatsMenu = uimenu(app.NewMenu);
            app.BioFormatsMenu.MenuSelectedFcn = createCallbackFcn(app, @BioFormatsMenuSelected, true);
            app.BioFormatsMenu.Text = 'BioFormats';
            app.DavisLabMenu = uimenu(app.NewMenu);
            app.DavisLabMenu.MenuSelectedFcn = createCallbackFcn(app, @DavisLabMenuSelected, true);
            app.DavisLabMenu.Text = 'DavisLab';
            app.ENVIMenu = uimenu(app.NewMenu);
            app.ENVIMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVIMenuSelected, true);
            app.ENVIMenu.Text = 'ENVI';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ReloadMenu = uimenu(app.File);
            app.ReloadMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadMenuSelected, true);
            app.ReloadMenu.Text = 'Reload';
            app.SaveMenu = uimenu(app.File);
            app.SaveMenu.Text = 'Save';
            app.SaveAnalysisMenu = uimenu(app.SaveMenu);
            app.SaveAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAnalysisMenuSelected, true);
            app.SaveAnalysisMenu.Text = 'Analysis';
            app.SaveAllAnalysesMenu = uimenu(app.SaveMenu);
            app.SaveAllAnalysesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllAnalysesMenuSelected, true);
            app.SaveAllAnalysesMenu.Text = 'All Analyses';
            app.SaveAsTiffStacksMenu = uimenu(app.SaveMenu);
            app.SaveAsTiffStacksMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsTiffStacksMenuSelected, true);
            app.SaveAsTiffStacksMenu.Text = 'As Tiff Stacks';
            app.SaveNewImageMenu = uimenu(app.SaveMenu);
            app.SaveNewImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNewImageMenuSelected, true);
            app.SaveNewImageMenu.Text = 'New Image';
            app.ExportAsMenu = uimenu(app.File);
            app.ExportAsMenu.Text = 'Export As';
            app.ExportMovieMenu = uimenu(app.ExportAsMenu);
            app.ExportMovieMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMovieMenuSelected, true);
            app.ExportMovieMenu.Text = 'Movie';
            app.ExportNewBasisSpectraMenu = uimenu(app.ExportAsMenu);
            app.ExportNewBasisSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportNewBasisSpectraMenuSelected, true);
            app.ExportNewBasisSpectraMenu.Text = 'New Basis Spectra';
            app.ExportTilesMenu = uimenu(app.ExportAsMenu);
            app.ExportTilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTilesMenuSelected, true);
            app.ExportTilesMenu.Text = 'Tiles';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SpectraViewerMenu = uimenu(app.ToolsMenu);
            app.SpectraViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectraViewerMenuSelected, true);
            app.SpectraViewerMenu.Text = 'SpectraViewer';
            app.SpeedtestCPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestCPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestCPUMenuSelected, true);
            app.SpeedtestCPUMenu.Text = 'Speedtest CPU';
            app.SpeedtestGPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestGPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestGPUMenuSelected, true);
            app.SpeedtestGPUMenu.Text = 'Speedtest GPU';
            app.HistogramAnalysisMenu = uimenu(app.ToolsMenu);
            app.HistogramAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramAnalysisMenuSelected, true);
            app.HistogramAnalysisMenu.Text = 'Histogram Analysis';
            app.ROIMenu = uimenu(app.ToolsMenu);
            app.ROIMenu.Text = 'ROI';
            app.NewROIMenu = uimenu(app.ROIMenu);
            app.NewROIMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIMenuSelected, true);
            app.NewROIMenu.Text = 'New ROI';
            app.AddROIMenu = uimenu(app.ROIMenu);
            app.AddROIMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROIMenuSelected, true);
            app.AddROIMenu.Text = 'Add ROI';
            app.NewPointMenu = uimenu(app.ROIMenu);
            app.NewPointMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPointMenuSelected, true);
            app.NewPointMenu.Text = 'New Point';
            app.AddPointMenu = uimenu(app.ROIMenu);
            app.AddPointMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPointMenuSelected, true);
            app.AddPointMenu.Text = 'Add Point';
            app.AddROItoBSLMenu = uimenu(app.ROIMenu);
            app.AddROItoBSLMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROItoBSLMenuSelected, true);
            app.AddROItoBSLMenu.Text = 'Add ROI to BSL';
            app.ClearLastROIMenu = uimenu(app.ROIMenu);
            app.ClearLastROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearLastROIMenuSelected, true);
            app.ClearLastROIMenu.Accelerator = 'Z';
            app.ClearLastROIMenu.Text = 'Clear Last ROI';
            app.AverageExptMenu = uimenu(app.ToolsMenu);
            app.AverageExptMenu.Text = 'Average Expt';
            app.PreunmixingMenu = uimenu(app.AverageExptMenu);
            app.PreunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PreunmixingMenuSelected, true);
            app.PreunmixingMenu.Text = 'Pre-unmixing';
            app.PostunmixingMenu = uimenu(app.AverageExptMenu);
            app.PostunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PostunmixingMenuSelected, true);
            app.PostunmixingMenu.Text = 'Post-unmixing';
            app.PhasorDenoisingMenu = uimenu(app.ToolsMenu);
            app.PhasorDenoisingMenu.MenuSelectedFcn = createCallbackFcn(app, @PhasorDenoisingMenuSelected, true);
            app.PhasorDenoisingMenu.Text = 'Phasor Denoising';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.IncludeAllSpectrainCompositeMenu = uimenu(app.OptionsMenu);
            app.IncludeAllSpectrainCompositeMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeAllSpectrainCompositeMenuSelected, true);
            app.IncludeAllSpectrainCompositeMenu.Text = 'Include All Spectra in Composite';
            app.IncludeSaturatedPixelsMenu = uimenu(app.OptionsMenu);
            app.IncludeSaturatedPixelsMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeSaturatedPixelsMenuSelected, true);
            app.IncludeSaturatedPixelsMenu.Text = 'Include Saturated Pixels';
            app.GaussianBlurMenu = uimenu(app.OptionsMenu);
            app.GaussianBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianBlurMenuSelected, true);
            app.GaussianBlurMenu.Text = 'Gaussian Blur';
            app.ApplyFlatfieldCorrectionMenu = uimenu(app.OptionsMenu);
            app.ApplyFlatfieldCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFlatfieldCorrectionMenuSelected, true);
            app.ApplyFlatfieldCorrectionMenu.Text = 'Apply Flatfield Correction';
            app.GlobalROIMenu = uimenu(app.OptionsMenu);
            app.GlobalROIMenu.MenuSelectedFcn = createCallbackFcn(app, @GlobalROIMenuSelected, true);
            app.GlobalROIMenu.Text = 'Global ROI';
            app.ApplyFiberImageFilterMenu = uimenu(app.OptionsMenu);
            app.ApplyFiberImageFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFiberImageFilterMenuSelected, true);
            app.ApplyFiberImageFilterMenu.Separator = 'on';
            app.ApplyFiberImageFilterMenu.Text = 'Apply Fiber Image Filter';
            app.SamplingCorrectionMenu = uimenu(app.OptionsMenu);
            app.SamplingCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @SamplingCorrectionMenuSelected, true);
            app.SamplingCorrectionMenu.Text = 'Sampling Correction';
            app.SpectralSmoothingMenu = uimenu(app.OptionsMenu);
            app.SpectralSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectralSmoothingMenuSelected, true);
            app.SpectralSmoothingMenu.Text = 'Spectral Smoothing';
            app.DemoMenu = uimenu(app.UIFigure);
            app.DemoMenu.Text = 'Demo';
            app.hyperCFMETestMenu = uimenu(app.DemoMenu);
            app.hyperCFMETestMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMETestMenuSelected, true);
            app.hyperCFMETestMenu.Text = 'hyperCFME Test';
            app.hyperCFMEDyesMenu = uimenu(app.DemoMenu);
            app.hyperCFMEDyesMenu.Text = 'hyperCFME Dyes';
            app.MaestrotestMenu = uimenu(app.DemoMenu);
            app.MaestrotestMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestrotestMenuSelected, true);
            app.MaestrotestMenu.Text = 'Maestro test';
            app.ENVItestapplesMenu = uimenu(app.DemoMenu);
            app.ENVItestapplesMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestapplesMenuSelected, true);
            app.ENVItestapplesMenu.Text = 'ENVI test - apples';
            app.ENVItestbloodstainsMenu = uimenu(app.DemoMenu);
            app.ENVItestbloodstainsMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestbloodstainsMenuSelected, true);
            app.ENVItestbloodstainsMenu.Enable = 'off';
            app.ENVItestbloodstainsMenu.Text = 'ENVI test - bloodstains';
            app.OlympustestMenu = uimenu(app.DemoMenu);
            app.OlympustestMenu.MenuSelectedFcn = createCallbackFcn(app, @OlympustestMenuSelected, true);
            app.OlympustestMenu.Enable = 'off';
            app.OlympustestMenu.Text = 'Olympus test';
            app.OIRtiffstestMenu = uimenu(app.DemoMenu);
            app.OIRtiffstestMenu.MenuSelectedFcn = createCallbackFcn(app, @OIRtiffstestMenuSelected, true);
            app.OIRtiffstestMenu.Enable = 'off';
            app.OIRtiffstestMenu.Text = 'OIR-tiffs test';
            app.ZeissMenu = uimenu(app.DemoMenu);
            app.ZeissMenu.MenuSelectedFcn = createCallbackFcn(app, @ZeissMenuSelected, true);
            app.ZeissMenu.Enable = 'off';
            app.ZeissMenu.Text = 'Zeiss';
            app.DebugcodeMenu = uimenu(app.DemoMenu);
            app.DebugcodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugcodeMenuSelected, true);
            app.DebugcodeMenu.Text = 'Debug code';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.ROIContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ROIContextMenu
            app.UIAxes1.ContextMenu = app.ROIContextMenu;
            app.UIAxes2.ContextMenu = app.ROIContextMenu;
            app.NewROIMenu_2 = uimenu(app.ROIContextMenu);
            app.NewROIMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NewROIMenuSelected, true);
            app.NewROIMenu_2.Text = 'New ROI';
            app.AddROIMenu_2 = uimenu(app.ROIContextMenu);
            app.AddROIMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AddROIMenuSelected, true);
            app.AddROIMenu_2.Text = 'Add ROI';
            app.NewPointMenu_2 = uimenu(app.ROIContextMenu);
            app.NewPointMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NewPointMenuSelected, true);
            app.NewPointMenu_2.Text = 'New Point';
            app.AddPointMenu_2 = uimenu(app.ROIContextMenu);
            app.AddPointMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AddPointMenuSelected, true);
            app.AddPointMenu_2.Text = 'Add Point';
            app.AddROItoBSLMenu_2 = uimenu(app.ROIContextMenu);
            app.AddROItoBSLMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AddROItoBSLMenuSelected, true);
            app.AddROItoBSLMenu_2.Text = 'Add ROI to BSL';
            app.ClearLastROIMenu_2 = uimenu(app.ROIContextMenu);
            app.ClearLastROIMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ClearLastROIMenuSelected, true);
            app.ClearLastROIMenu_2.Accelerator = 'Z';
            app.ClearLastROIMenu_2.Text = 'Clear Last ROI';
            app.AnalysisContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.AnalysisContextMenu
            app.UIAxes3.ContextMenu = app.AnalysisContextMenu;
            app.ShowErrorBarsMenu = uimenu(app.AnalysisContextMenu);
            app.ShowErrorBarsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowErrorBarsMenuSelected, true);
            app.ShowErrorBarsMenu.Text = 'Show Error Bars';
            app.ShowLegendMenu = uimenu(app.AnalysisContextMenu);
            app.ShowLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowLegendMenuSelected, true);
            app.ShowLegendMenu.Text = 'Show Legend';
            app.SelectDataMenu = uimenu(app.AnalysisContextMenu);
            app.SelectDataMenu.Enable = 'off';
            app.SelectDataMenu.Text = 'Select Data';
            app.BrushMenu = uimenu(app.SelectDataMenu);
            app.BrushMenu.MenuSelectedFcn = createCallbackFcn(app, @BrushMenuSelected, true);
            app.BrushMenu.Text = 'Brush';
            app.RectangleMenu = uimenu(app.SelectDataMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.LassoMenu = uimenu(app.SelectDataMenu);
            app.LassoMenu.MenuSelectedFcn = createCallbackFcn(app, @LassoMenuSelected, true);
            app.LassoMenu.Text = 'Lasso';
            app.EraserMenu = uimenu(app.SelectDataMenu);
            app.EraserMenu.MenuSelectedFcn = createCallbackFcn(app, @EraserMenuSelected, true);
            app.EraserMenu.Text = 'Eraser';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end